classdef NewMint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumIntGraphingCalculatorUIFigure  matlab.ui.Figure
        SettingsMenu               matlab.ui.container.Menu
        expSettings                matlab.ui.container.Menu
        changeBoundsOfSteps        matlab.ui.container.Menu
        animationMenu              matlab.ui.container.Menu
        animHigherQualityMenu      matlab.ui.container.Menu
        animHigherPerformanceMenu  matlab.ui.container.Menu
        animDisabledMenu           matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Debug                      matlab.ui.control.Label
        gStartDrawingButton        matlab.ui.control.Button
        fStartDrawingButton        matlab.ui.control.Button
        gClearDrawingButton        matlab.ui.control.Button
        fClearDrawingButton        matlab.ui.control.Button
        gTypeButton                matlab.ui.control.Button
        fTypeButton                matlab.ui.control.Button
        gDrawButton                matlab.ui.control.Button
        fDrawButton                matlab.ui.control.Button
        lowerBoundBox              matlab.ui.control.NumericEditField
        upperBoundBox              matlab.ui.control.NumericEditField
        stepBox                    matlab.ui.control.NumericEditField
        StepsSlider                matlab.ui.control.Slider
        StepsSliderLabel           matlab.ui.control.Label
        SelectSumTypeButtonGroup   matlab.ui.container.ButtonGroup
        SimpsonButton              matlab.ui.control.ToggleButton
        TrapButton                 matlab.ui.control.ToggleButton
        MidButton                  matlab.ui.control.ToggleButton
        RightButton                matlab.ui.control.ToggleButton
        LeftButton                 matlab.ui.control.ToggleButton
        bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        gxEditField                matlab.ui.control.EditField
        gxEditFieldLabel           matlab.ui.control.Label
        fxEditField                matlab.ui.control.EditField
        fxEditFieldLabel           matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        mousePosLabel              matlab.ui.control.Label
        SumErrorLabel              matlab.ui.control.Label
        SumLabel                   matlab.ui.control.Label
        IntegralLabel              matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Define x as symbolic variable
        x = sym("x")
        % Most up to date function variables -- direct user input
        newFx = symfun(0, sym("x"));
        newGx = symfun(0, sym("x"));
        % Slightly outdated function variables -- updated after anim.
        currentFx = symfun(0, sym("x"));
        currentGx = symfun(0, sym("x"));
        
        % Last input stored as a serial date number to avoid animation
        % problems from input spam
        fxLastIn;
        gxLastIn;

        % References to plotted fx, gx, and sum visualization
        fxPlot;
        gxPlot; 
        sumPlot;

        % Input data for calculating sums
        sumSteps = 10;
        lowerBound = -10;
        upperBound = 10;
        sumType = 'L';

        % Should animation be played
        animationEnabled = false;

        % Can animations be enabled at this time
        canAnimationEnable = true;

        % Number of points to be set during animation
        animationPoints;

        % Number of frames in animation
        animationFrames;

        % Input Keys
        ctrlDown = false;
        shiftDown = false;
        zDown = false;

        % Which drawing is currently active, if any.
        activeDrawing = '';

        % Most recently drawn point
        drawnLinePoint;

        % Lists to contain drawn piecewise functions
        fxDrawing = [0 0];
        gxDrawing = [0 0];

        % Is the line being drawn in increasing or decreasing order?
        increasing;
    end
    
    methods (Access = private)
        
        function shouldRedraw(app)
            %% Check if input function changed
            if app.newFx ~= app.currentFx
                if app.animationEnabled 
                    app.fxLastIn = now;
                    inputLimiter(app, 'f');
                else
                    redrawSums(app)
                end
            elseif app.newGx ~= app.currentGx
                if app.animationEnabled 
                    app.gxLastIn = now;
                    inputLimiter(app, 'g');
                else
                    redrawSums(app)
                end
            end
        end
        
        function redraw(app, currentFunction)
            % Store current screen bounds so screen doesn't move during anim
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            % Subdivide screen into n steps with n = visualQuality
            xSteps = linspace(xLimits(1), xLimits(2), app.animationPoints);

            % Preallocate operating/static function arrays
            operatingFunction = zeros(app.animationFrames, app.animationPoints);

            %% Set matrices for animated & static variables
            if currentFunction == 'f'
                for n = 1:1:app.animationFrames
                    % Calculate interp value at current iteration
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    % Set the state of the animated function for this frame
                    operatingFunction(n, :) = (1 - intFunc) * app.currentFx(xSteps) + intFunc * app.newFx(xSteps);
                end
                % As static fn doesn't move, it only needs to be set once
                staticFunction = app.currentGx(xSteps);
            else % See above
                for n = 1:1:app.animationFrames
                    intVal = n / app.animationFrames;
                    intFunc = intVal^2 * (3 - 2 * intVal);
                    operatingFunction(n, :) = (1 - intFunc) * app.currentGx(xSteps) + intFunc * app.newGx(xSteps);
                end
                staticFunction = app.currentFx(xSteps);
            end

            %% TODO: IF IMAGINARY/COMPLEX, **REMOVE TERM**
            %% Run the animation
            if currentFunction == 'f'
                for n = 1:1:app.animationFrames
                    % Plot this frame using precalculated arrays
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes, xSteps, operatingFunction(n, :));
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, xSteps, staticFunction);
                    plotAxes(app)
                    % Reset the limits so screen doesn't move
                    app.UIAxes.XLim = xLimits;
                    app.UIAxes.YLim = yLimits;
                    % Frame delay
                    pause(0.005)
                end
            else % See above
                for n = 1:1:app.animationFrames
                    % Order swapped to maintain colors
                    hold(app.UIAxes, "off");
                    plot(app.UIAxes, xSteps, staticFunction);
                    hold(app.UIAxes, "on");
                    set(app.UIAxes, xSteps, operatingFunction(n, :));
                    drawnow;
                    plotAxes(app)
                    pause(0.005)
                end
            end

            % Redraw sum approximations and graphs in full quality
            redrawSums(app);
        end
        
        function inputLimiter(app, funcIn)
            pause(0.5)
            if funcIn == 'f' && now > app.fxLastIn + 0.000005
               redraw(app, funcIn);
            elseif funcIn == 'g' && now > app.gxLastIn + 0.000005
             redraw(app, funcIn);
            end
        end
        
        function plotAxes(app)
            xline(app.UIAxes, 0, "Color", "#AAAAAA")
            yline(app.UIAxes, 0, "Color", "#AAAAAA")
        end
        
        function redrawSums(app)
           %% DISCLAIMER: This function primarily consists of heavily refactored ChatGPT code
           % Redraws the plot with the current inputs. Updates the current
           % stored functions and draws at highest quality - as such, only
           % call either when sum parameters updated or at end of animation. 
           % Also updates area and error calculations.
           
           % Update stored functions
           app.currentFx = app.newFx;
           app.currentGx = app.newGx;

           width = (app.upperBound - app.lowerBound) / app.sumSteps; % Width of each rectangle
           rectSpace = linspace(app.lowerBound, app.upperBound, app.sumSteps + 1); % Ensure correct spacing
           switch app.sumType
               case 'L'
                    x_rects = rectSpace(1:end-1);
                    heights_f = app.newFx(x_rects);
                    heights_g = app.newGx(x_rects);
               case 'R'
                    x_rects = rectSpace(2:end);
                    heights_f = app.newFx(x_rects);
                    heights_g = app.newGx(x_rects);
               case 'M'
                    x_rects = rectSpace(1:end-1);
                    heights_f = (app.newFx(x_rects) + app.newFx(rectSpace(2:end))) / 2;
                    heights_g = (app.newGx(x_rects) + app.newGx(rectSpace(2:end))) / 2;
               case 'T'
                    x_rects = rectSpace(1:end-1);
                    heights_f = app.newFx(x_rects);
                    heights_g = app.newGx(x_rects);
               case 'S'
                     x_rects = rectSpace;
                    heights_f = app.newFx(x_rects);
                    heights_g = app.newGx(x_rects);
               otherwise
                   error('Bad sumType in redrawSums')
           end

           % Compute area
           switch app.sumType
               case 'L' || 'R'
                   area_sum = sum((heights_f - heights_g) * width);
               case 'M'
                   area_sum = sum((app.newFx(rectSpace(1:end-1)) + app.newFx(rectSpace(2:end))) / 2 * width);
               case 'T'
                   % Use trapz here
               case 'S'
                   area_sum = (width/3) * (app.newFx(app.lowerBound) + 4*sum(app.newFx(rectSpace(2:2:end-1))) + 2*sum(app.newFx(rectSpace(3:2:end-2))) + app.newFx(app.upperBound));
           end

           %% TODO: Clear old rectangles, this isn't working.
            delete(app.sumPlot)

           % Draw rectangles or trapezoids based on method
           switch app.sumType
               case 'T'
                   for i = 1:length(x_rects)
                       % Coordinates for the trapezoid (slanted sides)
                       x_trap = [x_rects(i), x_rects(i) + width];  % x-coordinates of the trapezoid
                       y_trap_f = [heights_f(i), app.newFx(x_rects(i) + width)]; % y-coordinates for function f
                       y_trap_g = [heights_g(i), app.newGx(x_rects(i) + width)]; % y-coordinates for function g
                    
                       % Plot trapezoids between the two functions
                       app.sumPlot = fill(app.UIAxes, [x_trap, fliplr(x_trap)], [y_trap_f, fliplr(y_trap_g)], 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                   end
               case 'S'
                    % Calculate the midpoints for Simpson's rule
                    midpoints = (x_rects(1:end-1) + x_rects(2:end)) / 2;
                    
                    % Plot parabolic segments between each pair of points
                    for i = 1:length(x_rects)-1
                        % Get the three points for the current parabola
                        x_vals = [x_rects(i), midpoints(i), x_rects(i+1)];
                        
                        % Ensure y_vals_f and y_vals_g are of type double
                        y_vals_f = double(app.newFx(x_vals));
                        y_vals_g = double(app.newGx(x_vals));
                        
                        % Create the parabolic curve for the difference between functions f and g
                        x_parabola = linspace(x_vals(1), x_vals(3), 100);
                        y_parabola_f = interp1(x_vals, y_vals_f, x_parabola, 'pchip');
                        y_parabola_g = interp1(x_vals, y_vals_g, x_parabola, 'pchip');
                        
                        % Plot the filled area under the parabola
                        app.sumPlot = fill(app.UIAxes, [x_parabola, fliplr(x_parabola)], [y_parabola_f, fliplr(y_parabola_g)], 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                    end
               otherwise
                   for i = 1:length(x_rects)
                       x_rect = [x_rects(i), x_rects(i)+width, x_rects(i)+width, x_rects(i)];
                       y_rect = [heights_g(i), heights_g(i), heights_f(i), heights_f(i)];
                       app.sumPlot = fill(app.UIAxes, x_rect, y_rect, 'g', 'EdgeColor', 'k', 'FaceAlpha', 0.5);
                   end
           end
        end

        %% DRAWING FUNCTIONS

        function startLine(app, mousePos)
            % Keep bounds static
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;

            % Plot mousePos
            app.drawnLinePoint = plot(app.UIAxes, mousePos(1), mousePos(2), 'o');
            
            if app.activeDrawing == 'F'
                if app.fxDrawing == [0 0]
                    app.fxDrawing(end, :) = mousePos;
                end
                if length(app.fxDrawing(:, 1)) == 2
                    checkDir(app, app.fxDrawing)
                end
            else
                if app.gxDrawing == [0 0]
                    app.gxDrawing(end, :) = mousePos;
                end
                if length(app.gxDrawing(:, 1)) == 2
                    checkDir(app, app.gxDrawing)
                end
            end


            % Keep bounds static
            app.UIAxes.XLim = xLimits;
            app.UIAxes.YLim = yLimits;
        end
        
        function continueLine(app, mousePos)
            if app.activeDrawing == 'F'
                if (isscalar(app.fxDrawing(:, 1))) || (app.increasing && (mousePos(1) >= max(app.fxDrawing(:, 1)))) || (~app.increasing && (mousePos(1) <= min(app.fxDrawing(:, 1))))
                    app.fxDrawing(end + 1, :) = mousePos;
                else
                    warndlg('invalid pos')
                    return
                end
                delete(app.fxPlot);
                app.fxPlot = plot(app.UIAxes, app.fxDrawing(:, 1), app.fxDrawing(:, 2));
            else
                if (isscalar(app.gxDrawing(:, 1))) || (app.increasing && (mousePos(1) >= max(app.gxDrawing(:, 1)))) || (~app.increasing && (mousePos(1) <= min(app.gxDrawing(:, 1))))
                    app.gxDrawing(end + 1, :) = mousePos;
                else
                    warndlg('invalid pos')
                    return
                end
                delete(app.gxPlot);
                app.gxPlot = plot(app.UIAxes, app.gxDrawing(:, 1), app.gxDrawing(:, 2));
            end

            delete(app.drawnLinePoint);
            startLine(app, mousePos);
        end
        
        function checkDir(app, lineSet)
            if lineSet(2, 1) > lineSet(1,1)
                app.increasing = true;
            else 
                app.increasing = false;
            end
        end

        function toggleUIElement(app, buttonName, enabled)
            eval("app." + buttonName + ".Visible = " + enabled);
            eval("app." + buttonName + ".Enable = " + enabled);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set bounds and plot axes
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            plotAxes(app)
            hold(app.UIAxes, "on");
        end

        % Value changing function: fxEditField
        function fxEditFieldValueChanging(app, event)
            changingValue = event.Value;
            % If function is deleted, reset its graph to y = 0
            if isempty(changingValue)
                changingValue = "0";
            end

            try
                app.newFx = symfun(str2sym(changingValue), app.x);
                app.fxPlot.Color = "#0072BD";
                if numel(symvar(app.newFx)) > 1
                    error("must be in terms of x")
                end
                shouldRedraw(app);
            catch
                %% TODO: FIGURE OUT WHY THIS OCCASIONALLY RETURNS INVALID OR DELETED OBJECT INSTEAD OF TRY CATCH
                try
                    app.fxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Value changing function: gxEditField
        function gxEditFieldValueChanging(app, event)
            changingValue = event.Value;

            if isempty(changingValue)
                changingValue = "0";
            end

            try
                app.newGx = symfun(str2sym(changingValue), app.x);
                app.gxPlot.Color = "#D95319";
                if numel(symvar(app.newGx)) > 1
                    error("must be in terms of x")
                end
                shouldRedraw(app);
            catch
                try
                    app.gxPlot.Color = "#AAAAAA";
                catch
                end
            end
        end

        % Selection changed function: SelectSumTypeButtonGroup
        function SelectSumTypeButtonGroupSelectionChanged(app, event)
            % When changed, update the sum type used in calculations and rendering
            app.sumType = app.SelectSumTypeButtonGroup.SelectedObject.Text;
            %% TODO: FORCE SIMPSONS TO ROUND TO EVEN (with warndlg)
            %%
            redrawSums(app)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            % When changed, update the lower bound for sum calculations
            app.lowerBound = app.aEditField.Value;
            redrawSums(app)
        end

        % Value changed function: StepsSlider
        function StepsSliderValueChanged(app, event)
            % When changed, update the number of steps for sum calculations
            steps = app.StepsSlider.Value;
            steps = round(steps);
            %% TODO: FORCE SIMPSONS TO ROUND TO EVEN
            %%
            app.StepsSlider.Value = steps;
            app.stepBox.Value = steps;

            app.sumSteps = steps;
            redrawSums(app)        
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            % When changed, update the upper bound for sum calculations
            app.upperBound = app.bEditField.Value;
            redrawSums(app)
        end

        % Menu selected function: animHigherQualityMenu
        function animHigherQualityMenuSelected(app, event)
            if app.canAnimationEnable
                app.animationFrames = 20;
                app.animationPoints = 50;
                app.animationEnabled = true;
            else
                warndlg("Animation disabled while drawing is active.", "Alert!")
            end
        end

        % Menu selected function: animHigherPerformanceMenu
        function animHigherPerformanceMenuSelected(app, event)
            if app.canAnimationEnable
                app.animationFrames = 5;
                app.animationPoints = 30;
                app.animationEnabled = true;
            else
                warndlg("Animation disabled while drawing is active.", "Alert!")
            end
        end

        % Menu selected function: animDisabledMenu
        function animDisabledMenuSelected(app, event)
            app.animationEnabled = false;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            AboutBox();
        end

        % Value changed function: stepBox
        function stepBoxValueChanged(app, event)
            % When changed, update the number of steps for sum calculations
            steps = app.stepBox.Value;
            steps = min(max(steps, app.StepsSlider.Limits(1)), app.StepsSlider.Limits(2));
            %% TODO: FORCE SIMPSONS TO ROUND TO EVEN
            %%

            app.StepsSlider.Value = steps;
            app.stepBox.Value = steps;

            app.sumSteps = steps;
            redrawSums(app)            
        end

        % Value changed function: lowerBoundBox
        function lowerBoundBoxValueChanged(app, event)
            app.StepsSlider.Limits(1) = app.lowerBoundBox.Value;
        end

        % Value changed function: upperBoundBox
        function upperBoundBoxValueChanged(app, event)
            app.StepsSlider.Limits(2) = app.upperBoundBox.Value;
        end

        % Button pushed function: fDrawButton
        function fDrawButtonPushed(app, event)
            % If animations are enabled, disable and alert the user
            if app.animationEnabled
                % TODO: replace warndlg with popup in ui
                warndlg("Animation disabled while drawing is active.", "Alert!")
                app.canAnimationEnable = false;
                app.animationEnabled = false;
            end

            % Clear fx
            app.newFx = symfun(0, sym("x"));
            app.fxEditField.Value = "";
            shouldRedraw(app);

            % Disable UI Elements
            toggleUIElement(app, "fDrawButton", false)
            toggleUIElement(app, "fxEditField", false)

            % Enable Drawing UI Elements
            toggleUIElement(app, "fStartDrawingButton", true)
            toggleUIElement(app, "fClearDrawingButton", true)
            toggleUIElement(app, "fTypeButton", true)
        end

        % Button pushed function: gDrawButton
        function gDrawButtonPushed(app, event)
            % If animations are enabled, disable and alert the user
            if app.animationEnabled
                % TODO: replace warndlg with popup in ui
                warndlg("Animation disabled while drawing is active.")
                app.canAnimationEnable = false;
                app.animationEnabled = false;
            end

            % Clear gx
            app.newGx = symfun(0, sym("x"));
            app.gxEditField.Value = "";
            shouldRedraw(app);

            % Disable Typing UI Elements
            toggleUIElement(app, "gDrawButton", false)
            toggleUIElement(app, "gxEditField", false)

            % Enable Drawing UI Elements
            toggleUIElement(app, "gStartDrawingButton", true)
            toggleUIElement(app, "gClearDrawingButton", true)
            toggleUIElement(app, "gTypeButton", true)
        end

        % Button pushed function: fTypeButton
        function fTypeButtonPushed(app, event)
            if app.gDrawButton.Enable == true
                app.canAnimationEnable = true;
            end
            
            % Disable Drawing UI Elements
            toggleUIElement(app, "fStartDrawingButton", false)
            toggleUIElement(app, "fClearDrawingButton", false)
            toggleUIElement(app, "fTypeButton", false)
            app.fStartDrawingButton.Text = "Draw New f(x)";
            if app.activeDrawing ~= 'G'
                app.activeDrawing = '';
            end

            % Enable Typing UI Elements
            toggleUIElement(app, "fDrawButton", true)
            toggleUIElement(app, "fxEditField", true)

            delete(app.fxPlot)
            app.fxPlot = plot(app.UIAxes, 0, 0);
            delete(app.drawnLinePoint);
        end

        % Button pushed function: gTypeButton
        function gTypeButtonPushed(app, event)
            if app.fDrawButton.Enable == true
                app.canAnimationEnable = true;
            end

            % Disable Drawing UI Elements
            toggleUIElement(app, "gStartDrawingButton", false)
            toggleUIElement(app, "gClearDrawingButton", false)
            toggleUIElement(app, "gTypeButton", false)
            app.gStartDrawingButton.Text = "Draw New g(x)";
            if app.activeDrawing ~= 'F'
                app.activeDrawing = '';
            end

            % Enable Typing UI Elements
            toggleUIElement(app, "gDrawButton", true)
            toggleUIElement(app, "gxEditField", true)

            delete(app.gxPlot)
            app.gxPlot = plot(app.UIAxes, 0, 0);
            delete(app.drawnLinePoint);
        end

        % Button pushed function: fStartDrawingButton
        function fStartDrawingButtonPushed(app, event)
            if isempty(app.activeDrawing) || app.activeDrawing ~= 'F'
                    app.activeDrawing = 'F';
                    app.fxDrawing = [0 0];
                    delete(app.fxPlot);
                    delete(app.drawnLinePoint);

                    % Toggle button texts in the case g(x) was selected
                    app.gStartDrawingButton.Text = "Draw New g(x)";
                    app.fStartDrawingButton.Text = "Finish Drawing";
                    app.mousePosLabel.Text = "x:    y: ";
            else
                    app.activeDrawing = '';
                    app.Debug.Text = '';
                    app.fStartDrawingButton.Text = "Draw New f(x)";
                    app.mousePosLabel.Text = '';
                    delete(app.drawnLinePoint)
            end
        end

        % Button pushed function: gStartDrawingButton
        function gStartDrawingButtonPushed(app, event)
            if isempty(app.activeDrawing) || app.activeDrawing ~= 'G'
                    app.activeDrawing = 'G';
                    app.gxDrawing = [0 0];
                    delete(app.gxPlot);
                    delete(app.drawnLinePoint);

                    app.fStartDrawingButton.Text = "Draw New f(x)";
                    app.gStartDrawingButton.Text = "Finish Drawing";
                    app.mousePosLabel.Text = "x:    y: ";
            else
                    app.activeDrawing = '';
                    app.Debug.Text = '';
                    app.gStartDrawingButton.Text = "Draw New g(x)";
                    app.mousePosLabel.Text = '';
                delete(app.drawnLinePoint)

            end
        end

        % Window button motion function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowButtonMotion(app, event)
            if isempty(app.activeDrawing)
                return
            end

            mousePos = event.IntersectionPoint([1 2]);

            if ~(app.ctrlDown || app.shiftDown)
                mousePos = round(mousePos);
            end
            
            if ~isnan(mousePos)
                app.mousePosLabel.Text = "x: " + mousePos(1) + "   y: " + mousePos(2);
            else
                app.mousePosLabel.Text = "x:    y: ";
            end
        end

        % Window key press function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowKeyPress(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = true;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = true;
            end
        end

        % Window key release function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowKeyRelease(app, event)
            if isequal(event.Key, 'control')
                app.ctrlDown = false;
            elseif isequal(event.Key, 'shift')
                app.shiftDown = false;
            end
        end

        % Callback function
        function xLowerBoundBoxValueChanged(app, event)
            app.UIAxes.XLim(1) = app.xLowerBoundBox.Value;
            app.UIAxes.XLim(2) = app.xUpperBoundBox.Value;
            app.UIAxes.YLim(1) = app.yLowerBoundBox.Value;
            app.UIAxes.YLim(2) = app.yUpperBoundBox.Value;
        end

        % Window scroll wheel function: NumIntGraphingCalculatorUIFigure
        function NumIntGraphingCalculatorUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            zoomQuantity = -verticalScrollAmount * verticalScrollCount;
            
            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;

            small = 1 / zoomQuantity;
            large = 2 - small;

            app.UIAxes.XLim = [(large * xLim(1) + small * xLim(2)) / 2, (small * xLim(1) + large * xLim(2)) / 2];
            app.UIAxes.YLim = [(large * yLim(1) + small * yLim(2)) / 2, (small * yLim(1) + large * yLim(2)) / 2];
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if ~isempty(app.activeDrawing)
                mousePos = event.IntersectionPoint([1 2]);
    
                if ~(app.shiftDown || app.ctrlDown)
                    mousePos = round(mousePos);
                end
    

                if app.activeDrawing == 'F'
                    if app.fxDrawing == [0 0]
                        startLine(app, mousePos);
                    else
                        continueLine(app, mousePos);
                    end
                else
                    if app.gxDrawing == [0 0]
                        startLine(app, mousePos);
                    else
                        continueLine(app, mousePos);
                    end
                end
            end
        end

        % Button pushed function: fClearDrawingButton
        function fClearDrawingButtonPushed(app, event)
            if app.activeDrawing == 'F'
                app.activeDrawing = '';
                app.fStartDrawingButton.Text = "Draw New f(x)";
                delete(app.drawnLinePoint)
            end
            
            delete(app.fxPlot)
        end

        % Button pushed function: gClearDrawingButton
        function gClearDrawingButtonPushed(app, event)
            if app.activeDrawing == 'G'
                app.activeDrawing = '';
                app.gStartDrawingButton.Text = "Draw New g(x)";
                delete(app.drawnLinePoint)
            end
            
            delete(app.gxPlot)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NumIntGraphingCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {331, 331};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumIntGraphingCalculatorUIFigure = uifigure('Visible', 'off');
            app.NumIntGraphingCalculatorUIFigure.Name = 'NumInt Graphing Calculator';
            app.NumIntGraphingCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NumIntGraphingCalculatorUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowButtonMotion, true);
            app.NumIntGraphingCalculatorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowScrollWheel, true);
            app.NumIntGraphingCalculatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowKeyPress, true);
            app.NumIntGraphingCalculatorUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @NumIntGraphingCalculatorUIFigureWindowKeyRelease, true);
            app.SettingsMenu = uimenu(app.NumIntGraphingCalculatorUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.expSettings = uimenu(app.SettingsMenu);
            app.expSettings.Text = 'Experimental Settings';
            app.changeBoundsOfSteps = uimenu(app.expSettings);
            app.changeBoundsOfSteps.Text = 'Custom Step Bounds';
            app.animationMenu = uimenu(app.expSettings);
            app.animationMenu.Text = 'Animation';
            app.animHigherQualityMenu = uimenu(app.animationMenu);
            app.animHigherQualityMenu.MenuSelectedFcn = createCallbackFcn(app, @animHigherQualityMenuSelected, true);
            app.animHigherQualityMenu.Text = 'Higher Quality';
            app.animHigherPerformanceMenu = uimenu(app.animationMenu);
            app.animHigherPerformanceMenu.MenuSelectedFcn = createCallbackFcn(app, @animHigherPerformanceMenuSelected, true);
            app.animHigherPerformanceMenu.Text = 'Higher Performance';
            app.animDisabledMenu = uimenu(app.animationMenu);
            app.animDisabledMenu.MenuSelectedFcn = createCallbackFcn(app, @animDisabledMenuSelected, true);
            app.animDisabledMenu.Text = 'Disabled';
            app.AboutMenu = uimenu(app.SettingsMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.NumIntGraphingCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.LeftPanel, 'text');
            app.fxEditField.ValueChangingFcn = createCallbackFcn(app, @fxEditFieldValueChanging, true);
            app.fxEditField.Placeholder = 'Enter f(x)';
            app.gxEditFieldLabel = uilabel(app.LeftPanel);
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gxEditField = uieditfield(app.LeftPanel, 'text');
            app.gxEditField.ValueChangingFcn = createCallbackFcn(app, @gxEditFieldValueChanging, true);
            app.gxEditField.Placeholder = 'Enter g(x)';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = -10;
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 10;
            app.SelectSumTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectSumTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectSumTypeButtonGroupSelectionChanged, true);
            app.SelectSumTypeButtonGroup.Title = 'Select Sum Type';
            app.LeftButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.LeftButton.Tooltip = {'Left Sum'};
            app.LeftButton.Text = 'L';
            app.LeftButton.Value = true;
            app.RightButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.RightButton.Tooltip = {'Right Sum'};
            app.RightButton.Text = 'R';
            app.MidButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.MidButton.Tooltip = {'Midpoint Sum'};
            app.MidButton.Text = 'M';
            app.TrapButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.TrapButton.Tooltip = {'Trapezoidal Sum'};
            app.TrapButton.Text = 'T';
            app.SimpsonButton = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.SimpsonButton.Tooltip = {'Simpson''s Rule Sum'};
            app.SimpsonButton.Text = 'S';
            app.StepsSliderLabel = uilabel(app.LeftPanel);
            app.StepsSliderLabel.Text = '# Steps';
            app.StepsSlider = uislider(app.LeftPanel);
            app.StepsSlider.Limits = [0 20];
            app.StepsSlider.ValueChangedFcn = createCallbackFcn(app, @StepsSliderValueChanged, true);
            app.StepsSlider.Value = 10;
            app.stepBox = uieditfield(app.LeftPanel, 'numeric');
            app.stepBox.Limits = [0 Inf];
            app.stepBox.RoundFractionalValues = 'on';
            app.stepBox.ValueChangedFcn = createCallbackFcn(app, @stepBoxValueChanged, true);
            app.stepBox.Value = 10;
            app.upperBoundBox = uieditfield(app.LeftPanel, 'numeric');
            app.upperBoundBox.RoundFractionalValues = 'on';
            app.upperBoundBox.ValueChangedFcn = createCallbackFcn(app, @upperBoundBoxValueChanged, true);
            app.upperBoundBox.Value = 20;
            app.lowerBoundBox = uieditfield(app.LeftPanel, 'numeric');
            app.lowerBoundBox.Limits = [0 Inf];
            app.lowerBoundBox.RoundFractionalValues = 'on';
            app.lowerBoundBox.ValueChangedFcn = createCallbackFcn(app, @lowerBoundBoxValueChanged, true);
            app.fDrawButton = uibutton(app.LeftPanel, 'push');
            app.fDrawButton.ButtonPushedFcn = createCallbackFcn(app, @fDrawButtonPushed, true);
            app.fDrawButton.Tooltip = {'Draw Function by Hand'};
            app.fDrawButton.Text = 'D';
            app.gDrawButton = uibutton(app.LeftPanel, 'push');
            app.gDrawButton.ButtonPushedFcn = createCallbackFcn(app, @gDrawButtonPushed, true);
            app.gDrawButton.Tooltip = {'Draw Function by Hand'};
            app.gDrawButton.Text = 'D';
            app.fTypeButton = uibutton(app.LeftPanel, 'push');
            app.fTypeButton.ButtonPushedFcn = createCallbackFcn(app, @fTypeButtonPushed, true);
            app.fTypeButton.Enable = 'off';
            app.fTypeButton.Visible = 'off';
            app.fTypeButton.Tooltip = {'Enter Function by Text'};
            app.fTypeButton.Text = 'T';
            app.gTypeButton = uibutton(app.LeftPanel, 'push');
            app.gTypeButton.ButtonPushedFcn = createCallbackFcn(app, @gTypeButtonPushed, true);
            app.gTypeButton.Enable = 'off';
            app.gTypeButton.Visible = 'off';
            app.gTypeButton.Tooltip = {'Enter Function by Text'};
            app.gTypeButton.Text = 'T';
            app.fClearDrawingButton = uibutton(app.LeftPanel, 'push');
            app.fClearDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @fClearDrawingButtonPushed, true);
            app.fClearDrawingButton.Enable = 'off';
            app.fClearDrawingButton.Visible = 'off';
            app.fClearDrawingButton.Tooltip = {'Clear f(x)'};
            app.fClearDrawingButton.Text = 'C';
            app.gClearDrawingButton = uibutton(app.LeftPanel, 'push');
            app.gClearDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @gClearDrawingButtonPushed, true);
            app.gClearDrawingButton.Enable = 'off';
            app.gClearDrawingButton.Visible = 'off';
            app.gClearDrawingButton.Tooltip = {'Clear g(x)'};
            app.gClearDrawingButton.Text = 'C';
            app.fStartDrawingButton = uibutton(app.LeftPanel, 'push');
            app.fStartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @fStartDrawingButtonPushed, true);
            app.fStartDrawingButton.Enable = 'off';
            app.fStartDrawingButton.Visible = 'off';
            app.fStartDrawingButton.Tooltip = {'Draw New f(x)'};
            app.fStartDrawingButton.Text = 'Draw New f(x)';
            app.gStartDrawingButton = uibutton(app.LeftPanel, 'push');
            app.gStartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @gStartDrawingButtonPushed, true);
            app.gStartDrawingButton.Enable = 'off';
            app.gStartDrawingButton.Visible = 'off';
            app.gStartDrawingButton.Tooltip = {'Draw New g(x)'};
            app.gStartDrawingButton.Text = 'Draw New g(x)';
            app.Debug = uilabel(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sum Visualization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.IntegralLabel = uilabel(app.RightPanel);
            app.IntegralLabel.Text = 'Integral';
            app.SumLabel = uilabel(app.RightPanel);
            app.SumLabel.Text = '___ Sum';
            app.SumErrorLabel = uilabel(app.RightPanel);
            app.SumErrorLabel.Text = 'Sum Error';
            app.mousePosLabel = uilabel(app.RightPanel);
            app.mousePosLabel.Tooltip = {''};
            app.mousePosLabel.Text = '';
            % Show the figure after all components are created
            app.NumIntGraphingCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumIntGraphingCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumIntGraphingCalculatorUIFigure)
        end
    end
end